classdef DetectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChoosecharactertodetectLabel  matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        UppercharacterButton          matlab.ui.control.RadioButton
        LowerCharacterButton          matlab.ui.control.RadioButton
        Switch                        matlab.ui.control.ToggleSwitch
        UIAxesMainPanel               matlab.ui.control.UIAxes
        UIAxesUpperPanel              matlab.ui.control.UIAxes
        UIAxesLowerPanel              matlab.ui.control.UIAxes
        TextArea                      matlab.ui.control.TextArea
    end


    methods (Access = private)
    
        function results = detectStart(app)
            
            faceDetector = vision.CascadeObjectDetector();    
            videoFileReader = vision.VideoFileReader('E:\TBBT Training Set Data\video_test3.mp4');       
            videoFrame      = step(videoFileReader);
            bbox            = step(faceDetector, videoFrame);
            
            if bbox == 0
                videoFrame      = step(videoFileReader);
                bbox            = step(faceDetector, videoFrame);
            end

            
            videoFrame = insertShape(videoFrame, 'Rectangle', bbox);           
            imshow(videoFrame, 'Parent', app.UIAxesMainPanel);           
            
          
            bboxPoints = bbox2points(bbox(1, :));
            bboxPoints = bbox2points(bbox(1, :));

            sizeHolder = size(bbox);
            numberOfDetectedFaces = sizeHolder(1);
            

            bboxPointsArray = {};
            for i=1:numberOfDetectedFaces
               bboxPointsArray{i} = bbox2points(bbox(i, :));
            end

            % Detect feature points in the face region.
             faces = {};
             for i = 1:numberOfDetectedFaces
                 face = detectMinEigenFeatures(rgb2gray(videoFrame), 'ROI', bbox(i,:));
                 faces{i} = face;   
             end 


            imshow(videoFrame, 'Parent', app.UIAxesMainPanel);

            %for i=1:numberOfDetectedFaces
            %    plot(faces{i},'Parent', app.UIAxesMainPanel);
            %end


            pointTracker = vision.PointTracker('MaxBidirectionalError', 2);


            pointsArray = {};
            for i=1:numberOfDetectedFaces
                face = faces{i};
                pointsArray{i} = face.Location;
            end    

            AllPointsForFaces = zeros(0);
            for i=1:numberOfDetectedFaces
                AllPointsForFaces =  vertcat(AllPointsForFaces, pointsArray{i});
            end 

            initialize(pointTracker, AllPointsForFaces, videoFrame);

            videoPlayer  = vision.VideoPlayer('Position',...
                 [100 100 [size(videoFrame, 2), size(videoFrame, 1)]+30]);


            oldPoints = AllPointsForFaces;

            while ~isDone(videoFileReader)
   
            videoFrame = step(videoFileReader);
            [AllPointsForFaces, isFound] = step(pointTracker, videoFrame);   
            visiblePoints = AllPointsForFaces(isFound, :);  
            oldInliers = oldPoints(isFound, :);
    
            if size(visiblePoints, 1) >= 2 % need at least 2 points
        
        
            [xform, oldInliers, visiblePoints] = estimateGeometricTransform(...
                oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
            for i=1:numberOfDetectedFaces
                
                bboxPointsArray{i} = transformPointsForward(xform, bboxPointsArray{i});
                    
               
                bboxPolygon = reshape(bboxPointsArray{i}', 1, []);
                videoFrame = insertShape(videoFrame, 'Polygon', bboxPolygon, ...
                    'LineWidth', 2);
                    
              
                videoFrame = insertMarker(videoFrame, visiblePoints, '+', ...
                    'Color', 'white');       
            
                % Reset the points
                oldPoints = visiblePoints;
                setPoints(pointTracker, oldPoints);   
            end
        end
    
   
        step(videoPlayer, videoFrame);
        end
        
        % Clean up
        release(videoFileReader);
        release(videoPlayer);
        release(pointTracker);
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('E:\TBBT Training Set Data\Set\l4.png', 'Parent', app.UIAxesUpperPanel);
            imshow('E:\TBBT Training Set Data\Set\r31.png', 'Parent', app.UIAxesLowerPanel);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.UppercharacterButton
                %imshow('E:\TBBT Training Set Data\Set\two.png', app.Panel);
                
                %[filename, pathname] = uigetfile("two.png", 'E:\TBBT Training Set Data\Set\');
                 
                 %img = imread([pathname filename]);
                 %app.UIAxesMainPanel = uiaxes(app.UIFigure);
                 %app.UIAxesMainPanel.Position = [52 313 560 99];
                 %imshow('E:\TBBT Training Set Data\Set\two.png', 'Parent', app.UIAxesMainPanel);
                 % Showing the origional image
                 %imshow(img, 'Parent', app.UIAxesMainPanel);
                 %title('original image');
                 %disp("one");
            elseif selectedButton == app.LowerCharacterButton
                disp("two");
            else
                disp("none");
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.TextArea.Value = value;
            if 1 == strcmp(value, 'START')
                detectStart(app);
                disp('adsd');
            elseif 1 == strcmp(value, 'STOP')
                disp("ffsf");
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChoosecharactertodetectLabel = uilabel(app.UIFigure);
            app.ChoosecharactertodetectLabel.Text = 'Choose character to detect';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.UppercharacterButton = uiradiobutton(app.ButtonGroup);
            app.UppercharacterButton.Text = 'Upper character';
            app.UppercharacterButton.Value = true;
            app.LowerCharacterButton = uiradiobutton(app.ButtonGroup);
            app.LowerCharacterButton.Text = 'Lower Character';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'STOP', 'START'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'STOP';
            app.UIAxesMainPanel = uiaxes(app.UIFigure);
            app.UIAxesUpperPanel = uiaxes(app.UIFigure);
            app.UIAxesLowerPanel = uiaxes(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = DetectorApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end